classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveArrayButton              matlab.ui.control.Button
        LoadArrayButton              matlab.ui.control.Button
        NameArrayEditFieldLabel      matlab.ui.control.Label
        NameArrayEditField           matlab.ui.control.EditField
        UITable                      matlab.ui.control.Table
        Panel                        matlab.ui.container.Panel
        DropDownLabel                matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        TablehasnotbeenupdatedLabel  matlab.ui.control.Label
        AddRowButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        Tablehasnotbeenupdated % Description
    end

    properties (Access = public)
        NewTable % Description
    end


    methods (Access = private)

        % Button pushed function: SaveArrayButton
        function SaveArrayButtonPushed(app, event)
            prompt = {'Name file here: (No spaces and specify file type)'};
            title = 'Save Array As';
            dims = [1 35];
            definput = {'My_new_array.dat'};
            answer = inputdlg(prompt,title,dims,definput);
            str = string(answer)
            Mytable = app.UITable.Data
            str_table = string(Mytable)
            FileID = fopen(str,'w');
            formatSpec = '%s %s %s %s\n';
            [nrows,ncols] = size(str_table);
            for row = 1:nrows
                fprintf(FileID,formatSpec,str_table(row,:));
            end
            fclose(FileID);

        end

        % Value changing function: NameArrayEditField
        function NameArrayEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.NameArrayEditField.Value = changingValue;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
           app.TablehasnotbeenupdatedLabel.Text = 'Table has not been updated.';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app_practice_table_default (value, app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            N = size(app.UITable.Data,1);
            TMP = app.UITable.Data;
            TMP{N+1,1} = num2str(N+1);
            TMP(N+1,1) = {''};
            TMP(N+1,2) = {''};
            TMP(N+1,3) = {''};
            TMP(N+1,4) = {''};
            app.UITable.Data = TMP;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SaveArrayButton = uibutton(app.UIFigure, 'push');
            app.SaveArrayButton.ButtonPushedFcn = createCallbackFcn(app, @SaveArrayButtonPushed, true);
            app.SaveArrayButton.Text = 'Save Array';
            app.LoadArrayButton = uibutton(app.UIFigure, 'push');
            app.LoadArrayButton.Text = 'Load Array';
            app.NameArrayEditFieldLabel = uilabel(app.UIFigure);
            app.NameArrayEditFieldLabel.Text = 'Name Array:';
            app.NameArrayEditField = uieditfield(app.UIFigure, 'text');
            app.NameArrayEditField.ValueChangingFcn = createCallbackFcn(app, @NameArrayEditFieldValueChanging, true);
            app.NameArrayEditField.Value = 'Insert_Name_Here';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Default', 'Default 2', 'Option 3', 'Option 4'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Default';
            app.TablehasnotbeenupdatedLabel = uilabel(app.UIFigure);
            app.TablehasnotbeenupdatedLabel.Text = 'Table has not been updated Label';
            app.AddRowButton = uibutton(app.UIFigure, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end